classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DatosIMULabel                  matlab.ui.control.Label
        ControlPIDLabel                matlab.ui.control.Label
        InterfazMiniProyectoLabel      matlab.ui.control.Label
        EnviarButton                   matlab.ui.control.Button
        controladorDropDownLabel       matlab.ui.control.Label
        controladorDropDown            matlab.ui.control.DropDown
        AceleracinXEditFieldLabel      matlab.ui.control.Label
        AceleracinXEditField           matlab.ui.control.EditField
        AceleracinYEditFieldLabel      matlab.ui.control.Label
        AceleracinYEditField           matlab.ui.control.EditField
        AceleracinZEditFieldLabel      matlab.ui.control.Label
        AceleracinZEditField           matlab.ui.control.EditField
        GiroXEditFieldLabel            matlab.ui.control.Label
        GiroXEditField                 matlab.ui.control.EditField
        GiroYEditFieldLabel            matlab.ui.control.Label
        GiroYEditField                 matlab.ui.control.EditField
        GiroZEditFieldLabel            matlab.ui.control.Label
        GiroZEditField                 matlab.ui.control.EditField
        MostrarButton                  matlab.ui.control.Button
        InicioButton                   matlab.ui.control.Button
        ValorEditFieldLabel            matlab.ui.control.Label
        ValorEditField                 matlab.ui.control.NumericEditField
        ConstantenoenviadaLabel        matlab.ui.control.Label
        GuardarDatosLabel              matlab.ui.control.Label
        GuardarButton                  matlab.ui.control.Button
        NActualLabel                   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NombreArchivoEditFieldLabel    matlab.ui.control.Label
        NombreArchivoEditField         matlab.ui.control.EditField
        ArchivonoguardadoLabel         matlab.ui.control.Label
        NmerodeMuestrasEditFieldLabel  matlab.ui.control.Label
        NmerodeMuestrasEditField       matlab.ui.control.NumericEditField
        ActualizarButton               matlab.ui.control.Button
        Grafica                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %N % Description
        s
        dato_in
        N
        nombre
        bandera
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.s = serialport('COM4',115200);
           %app.N = floor(app.NmerodeMuestrasEditField.Value);
        end

        % Button down function: Grafica
        function GraficaButtonDown(app, event)
            
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            
            valor = app.ValorEditField.Value;
            %writeline(tivaC, valorSerial); 
            %s = serialport('COM4',115200);
            if app.controladorDropDown.Value =='P'
                write(app.s,'a',"char")
                write(app.s,valor,"uint8"); 
                app.ConstantenoenviadaLabel.Text = 'Kp enviada';
                
                elseif app.controladorDropDown.Value=='I'
                       write(app.s,'b',"char")
                       write(app.s,valor,"uint8"); 
                       app.ConstantenoenviadaLabel.Text = 'Ki enviada';
                       
                elseif app.controladorDropDown.Value=='D'
                      write(app.s,'c',"char")
                      write(app.s,valor,"uint8"); 
                      app.ConstantenoenviadaLabel.Text = 'Kd enviada';
            end


        end

        % Button pushed function: MostrarButton
        function MostrarButtonPushed(app, event)
            %Obtenemos todos los datos del array para mostrarlos en la
            %interfaz
            app.AceleracinXEditField.Value = app.dato_in(2);
            app.AceleracinYEditField.Value = app.dato_in(3);
            app.AceleracinZEditField.Value = app.dato_in(4);
            app.GiroXEditField.Value = app.dato_in(5);
            app.GiroYEditField.Value = app.dato_in(6);
            app.GiroZEditField.Value = app.dato_in(7);
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            %app.ValorEditField.Value = app.N; 
            %N=1000;
            K = 10;
            k = 1;
            %vector
            %t = linspace(0,app.N);
            %data = zeros(500,1);        
            delete(instrfind);
            %Se configura el puerto en el que esta conectado la tivaC
            %Y el baudrate al que se operara para la comunicación
            %app.s = serialport('COM4',115200);
            
            muestra = zeros(app.N,1);
            %ylim(app.Grafica,[-15,15]);
            %xlim auto;
            h = plot(app.Grafica,muestra,'-r');
            
            %Ciclo for
            while (1)  
                for n = 1:app.N
                    buffer = readline(app.s);
                    app.dato_in = split(buffer,"!"); 
                    grafica(k) = str2double(app.dato_in(1));
                             
                    if(k == K)
                        h.YData((n-K+1):n) = int8(grafica);   % Asume que K es factor de N. De lo contrario,
                                                        % hay que hacer ajustes adicionales.
                        drawnow limitrate
                        k = 1;
                    else
                        k = k + 1;    
                    end
                    
                end
                muestra = zeros(app.N,1);
                %ylim(app.Grafica,[-15,15]);
                %xlim auto;
                h = plot(app.Grafica,muestra,'-r');
                
                if (app.bandera == 1)
                    for n = 1:app.N  
                        dato = str2double(app.dato_in);
                        fileID = fopen(app.nombre,"a");
                        fmt = '\n%5d %5d %5d %5d %5d %5d %5d\n';
                        fprintf(fileID,fmt,dato);
                        fclose(fileID);
                    end
                    app.bandera = 0;
                    app.ArchivonoguardadoLabel.Text = 'Guardado con Exito';
                end
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            app.bandera = 1;
            app.nombre = app.NombreArchivoEditField.Value;
            fileID = fopen(app.nombre,'w');
            titulo = ['Ángulo Y' 'Accel X' 'Accel Y' 'Accel Z' 'Giro X' 'Giro Y' 'Giro Z'];
            fmt = '%s %s %s %s %s %s %s\n';
            fprintf(fileID,fmt, titulo);
            fclose(fileID);
        end

        % Callback function
        function NSliderValueChanged(app, event)
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.N = app.NmerodeMuestrasEditField.Value;
            app.Label.Text = num2str(app.N);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosIMULabel = uilabel(app.UIFigure);
            app.DatosIMULabel.Text = 'Datos IMU';
            app.ControlPIDLabel = uilabel(app.UIFigure);
            app.ControlPIDLabel.Text = 'Control PID';
            app.InterfazMiniProyectoLabel = uilabel(app.UIFigure);
            app.InterfazMiniProyectoLabel.Text = 'Interfaz MiniProyecto';
            app.EnviarButton = uibutton(app.UIFigure, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.controladorDropDownLabel = uilabel(app.UIFigure);
            app.controladorDropDownLabel.Text = {'controlador'; ''};
            app.controladorDropDown = uidropdown(app.UIFigure);
            app.controladorDropDown.Items = {'P', 'I', 'D'};
            app.controladorDropDown.Value = 'P';
            app.AceleracinXEditFieldLabel = uilabel(app.UIFigure);
            app.AceleracinXEditFieldLabel.Text = 'Aceleración X';
            app.AceleracinXEditField = uieditfield(app.UIFigure, 'text');
            app.AceleracinYEditFieldLabel = uilabel(app.UIFigure);
            app.AceleracinYEditFieldLabel.Text = 'Aceleración Y';
            app.AceleracinYEditField = uieditfield(app.UIFigure, 'text');
            app.AceleracinZEditFieldLabel = uilabel(app.UIFigure);
            app.AceleracinZEditFieldLabel.Text = 'Aceleración Z';
            app.AceleracinZEditField = uieditfield(app.UIFigure, 'text');
            app.GiroXEditFieldLabel = uilabel(app.UIFigure);
            app.GiroXEditFieldLabel.Text = 'Giro X';
            app.GiroXEditField = uieditfield(app.UIFigure, 'text');
            app.GiroYEditFieldLabel = uilabel(app.UIFigure);
            app.GiroYEditFieldLabel.Text = 'Giro Y';
            app.GiroYEditField = uieditfield(app.UIFigure, 'text');
            app.GiroZEditFieldLabel = uilabel(app.UIFigure);
            app.GiroZEditFieldLabel.Text = 'Giro Z';
            app.GiroZEditField = uieditfield(app.UIFigure, 'text');
            app.MostrarButton = uibutton(app.UIFigure, 'push');
            app.MostrarButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarButtonPushed, true);
            app.MostrarButton.Text = 'Mostrar';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = {'Inicio'; ''};
            app.ValorEditFieldLabel = uilabel(app.UIFigure);
            app.ValorEditFieldLabel.Text = 'Valor';
            app.ValorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConstantenoenviadaLabel = uilabel(app.UIFigure);
            app.ConstantenoenviadaLabel.Text = 'Constante no enviada';
            app.GuardarDatosLabel = uilabel(app.UIFigure);
            app.GuardarDatosLabel.Text = 'Guardar Datos';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NActualLabel = uilabel(app.UIFigure);
            app.NActualLabel.Text = 'N - Actual:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '#';
            app.NombreArchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombreArchivoEditFieldLabel.Text = 'Nombre Archivo';
            app.NombreArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.ArchivonoguardadoLabel = uilabel(app.UIFigure);
            app.ArchivonoguardadoLabel.Text = 'Archivo no guardado';
            app.NmerodeMuestrasEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeMuestrasEditFieldLabel.Text = 'Número de Muestras';
            app.NmerodeMuestrasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodeMuestrasEditField.Limits = [100 1000];
            app.NmerodeMuestrasEditField.Value = 100;
            app.ActualizarButton = uibutton(app.UIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Gráfica Ángulo Balancin')
            xlabel(app.Grafica, 'Muestras')
            ylabel(app.Grafica, 'Angulo')
            zlabel(app.Grafica, 'Z')
            app.Grafica.XTick = [0 1000];
            app.Grafica.XTickLabel = {'0'; 'N'};
            app.Grafica.YTick = [-15 -5 0 5 15];
            app.Grafica.YTickLabel = {'-15'; '-5'; '0'; '5'; '15'};
            app.Grafica.ButtonDownFcn = createCallbackFcn(app, @GraficaButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end